classdef ProyectoPlacas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        EditFieldResultado  matlab.ui.control.EditField
        SistemadereconocimientodeplacasLabel  matlab.ui.control.Label
        StopButton          matlab.ui.control.Button
        IniciarButton       matlab.ui.control.Button
        UIAxesBW            matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        cam; % Description
        timer;
        imBW;
       
    end
    
    methods (Access = private)
        
        function updateBWImage(app)
             % Capturar el frame de la webcam
            frame = snapshot(app.cam);
            [~,cc]=size(frame);
            picture=imresize(frame,[300 500]);
            if size(picture,3)==3
            picture=rgb2gray(picture);
            end
            threshold = graythresh(picture);

            imbn = imbinarize(picture,threshold);
            
            imgbwInv = imcomplement(imbn);
            
            [rows, cols] = size(imgbwInv);
            startRow = round(0.15 * rows);
            endRow = round(0.85 * rows);
            startCol = round(0.1 * cols);
            endCol = round(0.9 * cols);

            imgbwInvCropped = imcrop(imgbwInv, [startCol, startRow, endCol - startCol, endRow - startRow]);

            imgPro = bwareaopen(imgbwInvCropped,1000);

            if cc>2000
                imgPro1=bwareaopen(imgPro,6500);
            else
                imgPro1=bwareaopen(imgPro,5500);
            end
            picture2=imgPro-imgPro1;
            set(app.imBW, 'CData', repmat(picture2, [1, 1, 3])); % Repetir la imagen para tener tres canales de color  


            % Mostrar la imagen en el eje en blanco y negro
            picture2=bwareaopen(picture2,800);
            [L,Ne]=bwlabel(picture2);
            propiedades = regionprops(L, 'BoundingBox', 'Area');

            indicesRegion = find([propiedades.Area] >= 800);
            

            disp(['Número de regiones encontradas: ', num2str(Ne)]);
            if(length(indicesRegion) == 6 )
            for i = 1:length(indicesRegion)
                valorActual = app.EditFieldResultado.Value;
                indiceActual = indicesRegion(i);
                boundingBox = propiedades(indiceActual).BoundingBox;

                % Redondear las coordenadas del cuadro delimitador para evitar errores de índice
                boundingBox = round(boundingBox);

                % Extraer la región de interés de la imagen original
                imagenRegion = picture2(boundingBox(2):(boundingBox(2) + boundingBox(4) - 1), ...
                         boundingBox(1):(boundingBox(1) + boundingBox(3) - 1), :);
                bordes = 22; % ajusta este valor según sea necesario
                imagenBordes = padarray(imagenRegion, [bordes, bordes], 0);
                imagenInvertida = imcomplement(imagenBordes);
                imgInvF = medfilt2(imagenInvertida, [3 3]);
                figure;
                imshow(imgInvF);
                title(['Región ', num2str(i)]);


                imagenRedimensionada = imresize(imgInvF, [40, 40]).';
                vectorImagen = reshape(imagenRedimensionada, 1, []);
                str = load('Model96.mat');
                % Almacena la imagen en la celda de resultados
                caracteres = '0123456789ABCDEFGHIJKLMNOPQRSTUVWXYZ';
                [yfit] = str.Model96.predictFcn(vectorImagen);
                if yfit >= 0 && yfit <= 35
                    nuevoValor = [valorActual, caracteres(yfit + 1)];
                else
                    disp('Valor no reconocido');
                end
                    app.EditFieldResultado.Value = nuevoValor;
            end
                stop(app.timer);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IniciarButton
        function IniciarButtonPushed(app, event)
             % Obtener la lista de cámaras disponibles
    cameras = webcamlist;
    
    % Verificar si hay al menos una cámara disponible
    if ~isempty(cameras)
        % Seleccionar la cámara deseada (puedes ajustar según tus necesidades)
        selectedCameraIndex = 2;  % Elige la cámara que deseas utilizar
        selectedCameraName = cameras{selectedCameraIndex};

        % Inicializar la cámara seleccionada
        app.cam = webcam(selectedCameraName);
        
        % Resto del código...
        
        % Configurar el eje para mostrar el video en color
        frame = snapshot(app.cam);
        imColor = image(app.UIAxes, zeros(size(frame), 'uint8'));
        axis(app.UIAxes, 'image');
        preview(app.cam, imColor);

        % Configurar el eje para mostrar el video en blanco y negro
        app.imBW = image(app.UIAxesBW, zeros(size(imColor), 'uint8'));
        axis(app.UIAxesBW, 'image');

        % Configurar el temporizador para actualizar la imagen en blanco y negro
        app.timer = timer('ExecutionMode', 'fixedRate', 'Period', 0.3, ...
            'TimerFcn', @(~,~) updateBWImage(app));
        start(app.timer);
        
    else
        disp('No se encontraron cámaras disponibles.');
    end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % Detener el temporizador
            stop(app.timer);

            % Detener la webcam
            clear app.cam;

            % Puedes ocultar o borrar la imagen en los Axes si lo deseas
            cla(app.UIAxes);
            cla(app.UIAxesBW);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Webcam')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxesBW = uiaxes(app.UIFigure);
            title(app.UIAxesBW, 'Imagen Procesada')
            app.UIAxesBW.XTick = [];
            app.UIAxesBW.YTick = [];
            app.IniciarButton = uibutton(app.UIFigure, 'push');
            app.IniciarButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarButtonPushed, true);
            app.IniciarButton.Text = 'Iniciar';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.SistemadereconocimientodeplacasLabel = uilabel(app.UIFigure);
            app.SistemadereconocimientodeplacasLabel.Text = 'Sistema de reconocimiento de placas';
            app.EditFieldResultado = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProyectoPlacas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end